classdef doan_matlab < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        NghimTab                       matlab.ui.container.Tab
        ThchinButton                   matlab.ui.control.Button
        SlnlpEditField                 matlab.ui.control.NumericEditField
        SlnlpLabel                     matlab.ui.control.Label
        KtqunghimEditField             matlab.ui.control.NumericEditField
        KtqunghimEditFieldLabel        matlab.ui.control.Label
        ChnphngphptmButtonGroup        matlab.ui.container.ButtonGroup
        NewtonButton                   matlab.ui.control.ToggleButton
        LpButton                       matlab.ui.control.ToggleButton
        ChiaiButton                    matlab.ui.control.ToggleButton
        NhpsaischophpEditField         matlab.ui.control.NumericEditField
        NhpsaischophpEditFieldLabel    matlab.ui.control.Label
        bEditField                     matlab.ui.control.NumericEditField
        bLabel                         matlab.ui.control.Label
        aEditField                     matlab.ui.control.NumericEditField
        aLabel                         matlab.ui.control.Label
        NhpkhongphnlynghimabLabel      matlab.ui.control.Label
        NhpphngtrnhEditField           matlab.ui.control.EditField
        NhpphngtrnhEditFieldLabel      matlab.ui.control.Label
        UIAxes                         matlab.ui.control.UIAxes
        NisuyTab                       matlab.ui.container.Tab
        ThchinButton_2                 matlab.ui.control.Button
        KtqunisuyEditField             matlab.ui.control.NumericEditField
        KtqunisuyEditFieldLabel        matlab.ui.control.Label
        NhpgitrcnnisuyEditField        matlab.ui.control.NumericEditField
        NhpgitrcnnisuyEditFieldLabel   matlab.ui.control.Label
        KtquathcnisuyEditField         matlab.ui.control.EditField
        KtquathcnisuyEditFieldLabel    matlab.ui.control.Label
        ChnphngphpnisuyButtonGroup     matlab.ui.container.ButtonGroup
        LagrangeButton                 matlab.ui.control.ToggleButton
        NewtonButton_2                 matlab.ui.control.ToggleButton
        Nhpdliuyvd123EditField         matlab.ui.control.EditField
        Nhpdliuyvd123EditFieldLabel    matlab.ui.control.Label
        Nhpdliuxvd123EditField         matlab.ui.control.EditField
        Nhpdliuxvd123EditFieldLabel    matlab.ui.control.Label
        UIAxes2                        matlab.ui.control.UIAxes
        HiquyTab                       matlab.ui.container.Tab
        ThchinButton_3                 matlab.ui.control.Button
        KtqudonEditField               matlab.ui.control.NumericEditField
        KtqudonEditFieldLabel          matlab.ui.control.Label
        NhpgitrcndonEditField          matlab.ui.control.NumericEditField
        NhpgitrcndonEditFieldLabel     matlab.ui.control.Label
        KtquphngtrnhhiquyEditField     matlab.ui.control.EditField
        KtquphngtrnhhiquyEditFieldLabel  matlab.ui.control.Label
        Nhpdliuyvd123EditField_2       matlab.ui.control.EditField
        Nhpdliuyvd123EditField_2Label  matlab.ui.control.Label
        Nhpdliuxvd123EditField_2       matlab.ui.control.EditField
        Nhpdliuxvd123EditField_2Label  matlab.ui.control.Label
        ChnphngphphiquyButtonGroup     matlab.ui.container.ButtonGroup
        LogaritButton                  matlab.ui.control.ToggleButton
        HmmButton                      matlab.ui.control.ToggleButton
        TuyntnhButton                  matlab.ui.control.ToggleButton
        UIAxes2_2                      matlab.ui.control.UIAxes
        ohmTab                         matlab.ui.container.Tab
        ThchinButton_4                 matlab.ui.control.Button
        KtquEditField                  matlab.ui.control.NumericEditField
        KtquEditFieldLabel             matlab.ui.control.Label
        NhpgitrcntnhohmEditField       matlab.ui.control.NumericEditField
        NhpgitrcntnhohmEditFieldLabel  matlab.ui.control.Label
        ChnphngphpohmButtonGroup       matlab.ui.container.ButtonGroup
        TrungtmButton                  matlab.ui.control.ToggleButton
        LiButton                       matlab.ui.control.ToggleButton
        TinButton                      matlab.ui.control.ToggleButton
        ChngitrsaisButtonGroup         matlab.ui.container.ButtonGroup
        Oh2Button                      matlab.ui.control.ToggleButton
        OhButton                       matlab.ui.control.ToggleButton
        Tchvonuchncchnhpdliuth2CheckBox  matlab.ui.control.CheckBox
        NhpbchEditField                matlab.ui.control.NumericEditField
        NhpbchEditFieldLabel           matlab.ui.control.Label
        NhphmsEditField                matlab.ui.control.EditField
        NhphmsEditFieldLabel           matlab.ui.control.Label
        HocLabel                       matlab.ui.control.Label
        Nhpdliuyvd123EditField_3       matlab.ui.control.EditField
        Nhpdliuyvd123EditField_3Label  matlab.ui.control.Label
        Nhpdliuxvd123EditField_3       matlab.ui.control.EditField
        Nhpdliuxvd123EditField_3Label  matlab.ui.control.Label
        TichPhanTab                    matlab.ui.container.Tab
        KetQuaEditField_2              matlab.ui.control.EditField
        KtquLabel                      matlab.ui.control.Label
        Image                          matlab.ui.control.Image
        NhapNEditField                 matlab.ui.control.EditField
        NhpNEditFieldLabel             matlab.ui.control.Label
        ChonPPButtonGroup              matlab.ui.container.ButtonGroup
        Simpson38Button                matlab.ui.control.RadioButton
        Simpson13Button                matlab.ui.control.RadioButton
        HinhThangButton                matlab.ui.control.RadioButton
        ThucHienButton_5               matlab.ui.control.Button
        NhapCanEditField               matlab.ui.control.EditField
        NhpcntnhTchphnvd15Label        matlab.ui.control.Label
        NhapHamEditField_2             matlab.ui.control.EditField
        NhphmsEditField_2Label         matlab.ui.control.Label
        HocLabel_2                     matlab.ui.control.Label
        NhapYEditField_4               matlab.ui.control.EditField
        Nhpdliuyvd123Label             matlab.ui.control.Label
        NhapXEditField_4               matlab.ui.control.EditField
        Nhpdliuxvd123Label             matlab.ui.control.Label
        GiithiunhmTab                  matlab.ui.container.Tab
    end

    
    methods (Access = private)
        
        % Phương pháp Hình thang
            function result = HinhThang(~, x, y, N)
                h = (x(end)-x(1))/N;
                sumf = 0;
                for i=1:N-1
                sumf = sumf + y(1+i);
                end
                result = (h/2)*(y(1)+y(end)+2*sumf);
            end

            % Phương pháp Simpson 1/3
            function result = Simpson13(~, x, y, N)
                if mod(N,2) ~= 0
                    error('N phải là số chia hết cho 2 khi áp dụng Simpson 1/3');
                end
                h = (x(end) - x(1))/N;
                sumf_chan = 0;
                sumf_le = 0;

                % Tính tổng các thành phần y ở vị trí chẵn
                for i=1:2:N-1
                    sumf_chan = sumf_chan + y(1+i);
                end

                % Tính tổng các thành phần y ở vị trí lẻ
                for i=2:2:N-1
                    sumf_le = sumf_le + y(1+i);
                end
                result = (h/3)*(y(1) + y(end) + 4*sumf_chan + 2*sumf_le);
            end

            % Phương pháp Simpson 3/8
            function result = Simpson38(~, x, y, N)
                if mod(N,3) ~= 0
                    error('N phải là số chia hết cho 3 khi áp dụng Simpson 3/8');
                end
                h = (x(end) - x(1)) / N;
                result = y(1) + y(end);
                for i = 2:3:N-1
                    result = result + 3 * (y(i) + y(i + 1));
                end
                for i = 4:3:N - 1
                    result = result + 2 * y(i);
                end
                result = result * 3 * h / 8;
            end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ThchinButton_2
        function ThucHienNoiSuyButton_2Pushed(app, event)
            % Đọc dữ liệu từ EditField
            x = str2num(app.Nhpdliuxvd123EditField.Value); %#ok<ST2NM>
            y = str2num(app.Nhpdliuyvd123EditField.Value); %#ok<ST2NM>
            x_interp = app.NhpgitrcnnisuyEditField.Value; % Giá trị cần nội suy
            
            % Kiểm tra tính hợp lệ của dữ liệu
            if isempty(x) || isempty(y) || length(x) ~= length(y)
                uialert(app.UIFigure, 'Dữ liệu x và y không hợp lệ hoặc không khớp nhau!', 'Lỗi');
                return;
            end
    
            % Xác định phương pháp nội suy từ ButtonGroup
            method = app.ChnphngphpnisuyButtonGroup.SelectedObject.Text;
        
            % Tạo đa thức nội suy
            syms X; % Biến đại diện cho x trong đa thức
            n = length(x);
            p = 0; % Khởi tạo đa thức
            
            switch method
                case 'Newton'
                    % Tính nội suy Newton
                    divided_diff = zeros(n, n);
                    divided_diff(:, 1) = y';
        
                    for j = 2:n
                        for i = j:n
                            divided_diff(i, j) = (divided_diff(i, j-1) - divided_diff(i-1, j-1)) / (x(i) - x(i-j+1));
                        end
                    end
        
                    term = 1;
                    p = divided_diff(1, 1);
                    for k = 2:n
                        term = term * (X - x(k-1));
                        p = p + divided_diff(k, k) * term;
                    end
                case 'Lagrange'
                    % Tính nội suy Lagrange
                    for i = 1:n
                        L = 1;
                        for j = 1:n
                            if i ~= j
                                L = L * (X - x(j)) / (x(i) - x(j));
                            end
                        end
                        p = p + y(i) * L;
                    end
            end
        
            % Tính và hiển thị kết quả nội suy tại điểm cụ thể
            y_interp = double(subs(p, x_interp));
            app.KtqunisuyEditField.Value = y_interp;
        
            % Hiển thị đa thức nội suy vào ô kết quả
            app.KtquathcnisuyEditField.Value = char(vpa(p, 4));
        
            % Vẽ đồ thị nội suy
            fplot(app.UIAxes2, p, [min(x)-1, max(x)+1], 'b-', 'LineWidth', 1.5); % Hàm nội suy
            hold(app.UIAxes2, 'on');
            plot(app.UIAxes2, x, y, 'ro', 'MarkerSize', 8, 'LineWidth', 1.5); % Dữ liệu thực
            plot(app.UIAxes2, x_interp, y_interp, 'g*', 'MarkerSize', 10, 'LineWidth', 2); % Điểm nội suy
            hold(app.UIAxes2, 'off');
            
            % Cập nhật trục và nhãn
            title(app.UIAxes2, 'Nội suy Newton hoặc Lagrange');
            xlabel(app.UIAxes2, 'x');
            ylabel(app.UIAxes2, 'y');
            legend(app.UIAxes2, 'Hàm nội suy', 'Dữ liệu thực', 'Điểm nội suy', 'Location', 'Best');
        end

        % Button pushed function: ThucHienButton_5
        function ThucHienTichPhanButton_5Pushed(app, event)
           try
                % Nhập x, y, N
                x = str2num(app.NhapXEditField_4.Value);
                y = str2num(app.NhapYEditField_4.Value);
                N = str2num(app.NhapNEditField.Value);

                if isempty(x) && ~isempty(app.NhapHamEditField_2.Value)
                    f = app.NhapHamEditField_2.Value;
                    % Chuyển đổi toán tử cho phù hợp với mảng
                    f = strrep(f, '^', '.^');
                    f = strrep(f, '*', '.*');
                    f = strrep(f, '/', './');
                    f = str2func(['@(x)',f]);
                    Can = str2num(app.NhapCanEditField.Value);
                    x = linspace(Can(1), Can(2), N+1);
                    y = f(x);
                end

                if app.HinhThangButton.Value == 1
                    result = app.HinhThang(x,y,N);
                elseif app.Simpson13Button.Value == 1
                    result = app.Simpson13(x,y,N);
                elseif app.Simpson38Button.Value == 1
                    result = app.Simpson38(x,y,N);
                else 
                    error('Chưa chọn được phương pháp tính tích phân!');
                end

                % Hiển thị kết quả
                app.KetQuaEditField_2.Value = num2str(result);
                catch ME
                uialert(app.UIFigure, ME.message, 'Lỗi');
           end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.NghimTab = uitab(app.TabGroup);
            app.NghimTab.Title = 'Nghiệm';
            app.UIAxes = uiaxes(app.NghimTab);
            title(app.UIAxes, 'Đồ thị hàm số')
            xlabel(app.UIAxes, 'x')
            ylabel(app.UIAxes, 'y')
            zlabel(app.UIAxes, 'Z')
            app.NhpphngtrnhEditFieldLabel = uilabel(app.NghimTab);
            app.NhpphngtrnhEditFieldLabel.Text = 'Nhập phương trình';
            app.NhpphngtrnhEditField = uieditfield(app.NghimTab, 'text');
            app.NhpkhongphnlynghimabLabel = uilabel(app.NghimTab);
            app.NhpkhongphnlynghimabLabel.Text = 'Nhập khoảng phân ly nghiệm [a, b] ';
            app.aLabel = uilabel(app.NghimTab);
            app.aLabel.Text = 'a :';
            app.aEditField = uieditfield(app.NghimTab, 'numeric');
            app.bLabel = uilabel(app.NghimTab);
            app.bLabel.Text = 'b :';
            app.bEditField = uieditfield(app.NghimTab, 'numeric');
            app.NhpsaischophpEditFieldLabel = uilabel(app.NghimTab);
            app.NhpsaischophpEditFieldLabel.Text = 'Nhập sai số cho phép';
            app.NhpsaischophpEditField = uieditfield(app.NghimTab, 'numeric');
            app.ChnphngphptmButtonGroup = uibuttongroup(app.NghimTab);
            app.ChnphngphptmButtonGroup.Title = 'Chọn phương pháp tìm';
            app.ChiaiButton = uitogglebutton(app.ChnphngphptmButtonGroup);
            app.ChiaiButton.Text = 'Chia đôi';
            app.ChiaiButton.Value = true;
            app.LpButton = uitogglebutton(app.ChnphngphptmButtonGroup);
            app.LpButton.Text = 'Lặp';
            app.NewtonButton = uitogglebutton(app.ChnphngphptmButtonGroup);
            app.NewtonButton.Text = 'Newton';
            app.KtqunghimEditFieldLabel = uilabel(app.NghimTab);
            app.KtqunghimEditFieldLabel.Text = 'Kết quả nghiệm';
            app.KtqunghimEditField = uieditfield(app.NghimTab, 'numeric');
            app.SlnlpLabel = uilabel(app.NghimTab);
            app.SlnlpLabel.Text = 'Số lần lặp';
            app.SlnlpEditField = uieditfield(app.NghimTab, 'numeric');
            app.ThchinButton = uibutton(app.NghimTab, 'push');
            app.ThchinButton.Text = 'Thực hiện';
            app.NisuyTab = uitab(app.TabGroup);
            app.NisuyTab.Title = 'Nội suy';
            app.UIAxes2 = uiaxes(app.NisuyTab);
            title(app.UIAxes2, 'Hàm số nội suy và giá trị thực')
            xlabel(app.UIAxes2, 'x')
            ylabel(app.UIAxes2, 'y')
            zlabel(app.UIAxes2, 'Z')
            app.Nhpdliuxvd123EditFieldLabel = uilabel(app.NisuyTab);
            app.Nhpdliuxvd123EditFieldLabel.Text = 'Nhập dữ liệu x (vd: 1, 2, 3)';
            app.Nhpdliuxvd123EditField = uieditfield(app.NisuyTab, 'text');
            app.Nhpdliuyvd123EditFieldLabel = uilabel(app.NisuyTab);
            app.Nhpdliuyvd123EditFieldLabel.Text = 'Nhập dữ liệu y (vd: 1, 2, 3)';
            app.Nhpdliuyvd123EditField = uieditfield(app.NisuyTab, 'text');
            app.ChnphngphpnisuyButtonGroup = uibuttongroup(app.NisuyTab);
            app.ChnphngphpnisuyButtonGroup.Title = 'Chọn phương pháp nội suy';
            app.NewtonButton_2 = uitogglebutton(app.ChnphngphpnisuyButtonGroup);
            app.NewtonButton_2.Text = 'Newton';
            app.NewtonButton_2.Value = true;
            app.LagrangeButton = uitogglebutton(app.ChnphngphpnisuyButtonGroup);
            app.LagrangeButton.Text = 'Lagrange';
            app.KtquathcnisuyEditFieldLabel = uilabel(app.NisuyTab);
            app.KtquathcnisuyEditFieldLabel.Text = 'Kết quả đa thức nội suy';
            app.KtquathcnisuyEditField = uieditfield(app.NisuyTab, 'text');
            app.NhpgitrcnnisuyEditFieldLabel = uilabel(app.NisuyTab);
            app.NhpgitrcnnisuyEditFieldLabel.Text = 'Nhập giá trị cần nội suy';
            app.NhpgitrcnnisuyEditField = uieditfield(app.NisuyTab, 'numeric');
            app.KtqunisuyEditFieldLabel = uilabel(app.NisuyTab);
            app.KtqunisuyEditFieldLabel.Text = 'Kết quả nội suy';
            app.KtqunisuyEditField = uieditfield(app.NisuyTab, 'numeric');
            app.ThchinButton_2 = uibutton(app.NisuyTab, 'push');
            app.ThchinButton_2.ButtonPushedFcn = createCallbackFcn(app, @ThucHienNoiSuyButton_2Pushed, true);
            app.ThchinButton_2.Text = 'Thực hiện';
            app.HiquyTab = uitab(app.TabGroup);
            app.HiquyTab.Title = 'Hồi quy';
            app.UIAxes2_2 = uiaxes(app.HiquyTab);
            title(app.UIAxes2_2, 'Hàm số hồi quy và giá trị thực')
            xlabel(app.UIAxes2_2, 'x')
            ylabel(app.UIAxes2_2, 'y')
            zlabel(app.UIAxes2_2, 'Z')
            app.ChnphngphphiquyButtonGroup = uibuttongroup(app.HiquyTab);
            app.ChnphngphphiquyButtonGroup.Title = 'Chọn phương pháp hồi quy';
            app.TuyntnhButton = uitogglebutton(app.ChnphngphphiquyButtonGroup);
            app.TuyntnhButton.Text = 'Tuyến tính';
            app.TuyntnhButton.Value = true;
            app.HmmButton = uitogglebutton(app.ChnphngphphiquyButtonGroup);
            app.HmmButton.Text = 'Hàm mũ';
            app.LogaritButton = uitogglebutton(app.ChnphngphphiquyButtonGroup);
            app.LogaritButton.Text = 'Logarit';
            app.Nhpdliuxvd123EditField_2Label = uilabel(app.HiquyTab);
            app.Nhpdliuxvd123EditField_2Label.Text = 'Nhập dữ liệu x (vd: 1, 2, 3)';
            app.Nhpdliuxvd123EditField_2 = uieditfield(app.HiquyTab, 'text');
            app.Nhpdliuyvd123EditField_2Label = uilabel(app.HiquyTab);
            app.Nhpdliuyvd123EditField_2Label.Text = 'Nhập dữ liệu y (vd: 1, 2, 3)';
            app.Nhpdliuyvd123EditField_2 = uieditfield(app.HiquyTab, 'text');
            app.KtquphngtrnhhiquyEditFieldLabel = uilabel(app.HiquyTab);
            app.KtquphngtrnhhiquyEditFieldLabel.Text = 'Kết quả phương trình hồi quy';
            app.KtquphngtrnhhiquyEditField = uieditfield(app.HiquyTab, 'text');
            app.NhpgitrcndonEditFieldLabel = uilabel(app.HiquyTab);
            app.NhpgitrcndonEditFieldLabel.Text = 'Nhập giá trị cần dự đoán';
            app.NhpgitrcndonEditField = uieditfield(app.HiquyTab, 'numeric');
            app.KtqudonEditFieldLabel = uilabel(app.HiquyTab);
            app.KtqudonEditFieldLabel.Text = 'Kết quả dự đoán';
            app.KtqudonEditField = uieditfield(app.HiquyTab, 'numeric');
            app.ThchinButton_3 = uibutton(app.HiquyTab, 'push');
            app.ThchinButton_3.Text = 'Thực hiện';
            app.ohmTab = uitab(app.TabGroup);
            app.ohmTab.Title = 'Đạo hàm';
            app.Nhpdliuxvd123EditField_3Label = uilabel(app.ohmTab);
            app.Nhpdliuxvd123EditField_3Label.Text = 'Nhập dữ liệu x (vd: 1, 2, 3)';
            app.Nhpdliuxvd123EditField_3 = uieditfield(app.ohmTab, 'text');
            app.Nhpdliuyvd123EditField_3Label = uilabel(app.ohmTab);
            app.Nhpdliuyvd123EditField_3Label.Text = 'Nhập dữ liệu y (vd: 1, 2, 3)';
            app.Nhpdliuyvd123EditField_3 = uieditfield(app.ohmTab, 'text');
            app.HocLabel = uilabel(app.ohmTab);
            app.HocLabel.Text = 'Hoặc';
            app.NhphmsEditFieldLabel = uilabel(app.ohmTab);
            app.NhphmsEditFieldLabel.Text = 'Nhập hàm số';
            app.NhphmsEditField = uieditfield(app.ohmTab, 'text');
            app.NhpbchEditFieldLabel = uilabel(app.ohmTab);
            app.NhpbchEditFieldLabel.Text = 'Nhập bước h';
            app.NhpbchEditField = uieditfield(app.ohmTab, 'numeric');
            app.Tchvonuchncchnhpdliuth2CheckBox = uicheckbox(app.ohmTab);
            app.Tchvonuchncchnhpdliuth2CheckBox.Text = 'Tích vào nếu chọn cách nhập dữ liệu thứ 2';
            app.ChngitrsaisButtonGroup = uibuttongroup(app.ohmTab);
            app.ChngitrsaisButtonGroup.Title = 'Chọn giá trị sai số';
            app.OhButton = uitogglebutton(app.ChngitrsaisButtonGroup);
            app.OhButton.Text = 'O(h)';
            app.OhButton.Value = true;
            app.Oh2Button = uitogglebutton(app.ChngitrsaisButtonGroup);
            app.Oh2Button.Text = 'O(h^2)';
            app.ChnphngphpohmButtonGroup = uibuttongroup(app.ohmTab);
            app.ChnphngphpohmButtonGroup.Title = 'Chọn phương pháp đạo hàm ';
            app.TinButton = uitogglebutton(app.ChnphngphpohmButtonGroup);
            app.TinButton.Text = 'Tiến';
            app.TinButton.Value = true;
            app.LiButton = uitogglebutton(app.ChnphngphpohmButtonGroup);
            app.LiButton.Text = 'Lùi';
            app.TrungtmButton = uitogglebutton(app.ChnphngphpohmButtonGroup);
            app.TrungtmButton.Text = 'Trung tâm';
            app.NhpgitrcntnhohmEditFieldLabel = uilabel(app.ohmTab);
            app.NhpgitrcntnhohmEditFieldLabel.Text = 'Nhập giá trị cần tính đạo hàm';
            app.NhpgitrcntnhohmEditField = uieditfield(app.ohmTab, 'numeric');
            app.KtquEditFieldLabel = uilabel(app.ohmTab);
            app.KtquEditFieldLabel.Text = 'Kết quả';
            app.KtquEditField = uieditfield(app.ohmTab, 'numeric');
            app.ThchinButton_4 = uibutton(app.ohmTab, 'push');
            app.ThchinButton_4.Text = 'Thực hiện';
            app.TichPhanTab = uitab(app.TabGroup);
            app.TichPhanTab.Title = 'Tích phân';
            app.Nhpdliuxvd123Label = uilabel(app.TichPhanTab);
            app.Nhpdliuxvd123Label.Text = 'Nhập dữ liệu x (vd: 1 2 3)';
            app.NhapXEditField_4 = uieditfield(app.TichPhanTab, 'text');
            app.Nhpdliuyvd123Label = uilabel(app.TichPhanTab);
            app.Nhpdliuyvd123Label.Text = 'Nhập dữ liệu y (vd: 1 2 3)';
            app.NhapYEditField_4 = uieditfield(app.TichPhanTab, 'text');
            app.HocLabel_2 = uilabel(app.TichPhanTab);
            app.HocLabel_2.Text = 'Hoặc';
            app.NhphmsEditField_2Label = uilabel(app.TichPhanTab);
            app.NhphmsEditField_2Label.Text = 'Nhập hàm số';
            app.NhapHamEditField_2 = uieditfield(app.TichPhanTab, 'text');
            app.NhpcntnhTchphnvd15Label = uilabel(app.TichPhanTab);
            app.NhpcntnhTchphnvd15Label.Text = 'Nhập cận để tính Tích phân (vd: 1 5)';
            app.NhapCanEditField = uieditfield(app.TichPhanTab, 'text');
            app.ThucHienButton_5 = uibutton(app.TichPhanTab, 'push');
            app.ThucHienButton_5.ButtonPushedFcn = createCallbackFcn(app, @ThucHienTichPhanButton_5Pushed, true);
            app.ThucHienButton_5.Text = 'Thực hiện';
            app.ChonPPButtonGroup = uibuttongroup(app.TichPhanTab);
            app.ChonPPButtonGroup.Title = 'Chọn phương pháp tính tích phân';
            app.HinhThangButton = uiradiobutton(app.ChonPPButtonGroup);
            app.HinhThangButton.Text = 'Hình thang';
            app.HinhThangButton.Value = true;
            app.Simpson13Button = uiradiobutton(app.ChonPPButtonGroup);
            app.Simpson13Button.Text = 'Simpson 1/3';
            app.Simpson38Button = uiradiobutton(app.ChonPPButtonGroup);
            app.Simpson38Button.Text = 'Simpson 3/8';
            app.NhpNEditFieldLabel = uilabel(app.TichPhanTab);
            app.NhpNEditFieldLabel.Text = 'Nhập N';
            app.NhapNEditField = uieditfield(app.TichPhanTab, 'text');
            app.Image = uiimage(app.TichPhanTab);
            app.Image.ImageSource = 'maxresdefault.jpg';
            app.KtquLabel = uilabel(app.TichPhanTab);
            app.KtquLabel.Text = 'Kết quả';
            app.KetQuaEditField_2 = uieditfield(app.TichPhanTab, 'text');
            app.GiithiunhmTab = uitab(app.TabGroup);
            app.GiithiunhmTab.Title = 'Giới thiệu nhóm';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = doan_matlab

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end